classdef PupilGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatisticsPanel                 matlab.ui.container.Panel
        groupAnalyses                   matlab.ui.control.Button
        openStatPanel                   matlab.ui.control.Button
        ApplyStats                      matlab.ui.control.Button
        warningText                     matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        AveragePanel                    matlab.ui.container.Panel
        segmentTo                       matlab.ui.control.NumericEditField
        toEditFieldLabel_2              matlab.ui.control.Label
        fromEditFieldLabel_2            matlab.ui.control.Label
        segmentFrom                     matlab.ui.control.NumericEditField
        AverageSegmentCheckBox          matlab.ui.control.CheckBox
        GroupTrialsPanel                matlab.ui.container.Panel
        groupFilenameLabel              matlab.ui.control.Label
        nGroupsLabel                    matlab.ui.control.Label
        VariablesLabel                  matlab.ui.control.ListBox
        GroupbyVariableTextArea         matlab.ui.control.TextArea
        GroupbyVariableTextAreaLabel    matlab.ui.control.Label
        ApplyGroup                      matlab.ui.control.Button
        BalanceTrialsCheckBox           matlab.ui.control.CheckBox
        nVariablesLabel                 matlab.ui.control.Label
        LoadGroupFile                   matlab.ui.control.Button
        PlotPanel                       matlab.ui.container.Panel
        plotPosition                    matlab.ui.control.CheckBox
        plotLegend                      matlab.ui.control.CheckBox
        plotTimeAvg                     matlab.ui.control.CheckBox
        plotStats                       matlab.ui.control.CheckBox
        plotRawData                     matlab.ui.control.CheckBox
        plotAverage                     matlab.ui.control.CheckBox
        plotBaseline                    matlab.ui.control.CheckBox
        CurrentTrial                    matlab.ui.control.NumericEditField
        TrialEditFieldLabel             matlab.ui.control.Label
        TrialCounter                    matlab.ui.control.Label
        Right                           matlab.ui.control.Button
        Left                            matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        EpochPanel                      matlab.ui.container.Panel
        ApplyEpochButton                matlab.ui.control.Button
        postEditField                   matlab.ui.control.NumericEditField
        postEditFieldLabel              matlab.ui.control.Label
        preEditField                    matlab.ui.control.NumericEditField
        preEditFieldLabel               matlab.ui.control.Label
        triggerDropDown                 matlab.ui.control.DropDown
        triggerDropDownLabel            matlab.ui.control.Label
        filenameLabel                   matlab.ui.control.Label
        LoadFileButton                  matlab.ui.control.Button
        PreprocessingPanel              matlab.ui.container.Panel
        FilterandResamplePanel          matlab.ui.container.Panel
        HzEditField                     matlab.ui.control.NumericEditField
        HzEditFieldLabel                matlab.ui.control.Label
        resampleatCheckBox              matlab.ui.control.CheckBox
        FrequencyHzLabel                matlab.ui.control.Label
        lowPassSteepness                matlab.ui.control.NumericEditField
        SteepnessEditFieldLabel         matlab.ui.control.Label
        lowPassFrequency                matlab.ui.control.NumericEditField
        lowpassfilterCheckBox           matlab.ui.control.CheckBox
        ArtifactsButtonGroup            matlab.ui.container.ButtonGroup
        InterpolatemissingdataCheckBox  matlab.ui.control.CheckBox
        excludeTrialsPrctg              matlab.ui.control.NumericEditField
        excludeTrials                   matlab.ui.control.CheckBox
        RemoveArtifactsCheckBox         matlab.ui.control.CheckBox
        ArtifactSize                    matlab.ui.control.NumericEditField
        SizemsLabel                     matlab.ui.control.Label
        ArtifactTolerance               matlab.ui.control.NumericEditField
        TolerancesdLabel                matlab.ui.control.Label
        ApplyPreprocessing              matlab.ui.control.Button
        BaselinePanel                   matlab.ui.container.Panel
        baselineTo                      matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        baselineFrom                    matlab.ui.control.NumericEditField
        fromEditFieldLabel              matlab.ui.control.Label
        BaselineCheckBox                matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ppl = ppl_init();
    end
    
    methods (Access = private)
        
        function resetGUI(app)
            % on load new file, reset the GUI, if pupilab file then get
            % groups and other stuff
            
            app.plotLegend.Enable = 1;
            app.plotTimeAvg.Enable = 0;
            app.plotBaseline.Enable = 0;
            app.plotPosition.Enable = 1;
            app.plotAverage.Enable = 0;
            app.plotRawData.Enable = 0;
            app.plotStats.Enable = 0;
            
            app.groupFilenameLabel.Text = 'no file selected';
            app.nGroupsLabel.Text = '0 groups identifed';
            app.nVariablesLabel.Text = '0 variables identifed';
            app.VariablesLabel.Items = {'no variables'};
            app.VariablesLabel.Value = 'no variables';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ppl_dir = mfilename('fullpath');
            [path,file,ext] = fileparts(ppl_dir);
            addpath(path)
            addpath(genpath(path))
            
            [toInstall, textMsg] = ppl_dependenciesCheck(path);
            if ~isempty(toInstall)
                uialert(app.UIFigure , sprintf(textMsg),'Toolbox Installation Needed');
            end
            
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.edf;*.mat'});   
            if file ~= 0
                d = uiprogressdlg(app.UIFigure, 'Title', 'Loading', 'Indeterminate','on', 'Cancelable','off');
                app.ppl = ppl_load(fullfile(path, file));

                triggers = unique(app.ppl.event_info);
                triggers = triggers(~startsWith(triggers, '!') & ...
                    ~startsWith(triggers, 'VALIDATE') & ...
                    ~startsWith(triggers, 'EL'));
                
                resetGUI(app)
                app.triggerDropDown.Items = triggers;
                app.filenameLabel.Text = app.ppl.file;
                app.plotAverage.Value = 0;
                app.TrialCounter.Text = ['\' num2str( app.ppl.nTrials)];

%                 app.ppl = ppl_plot(app.UIAxes, app.ppl, app.plotBaseline.Value, [app.baselineFrom.Value app.baselineTo.Value], app.plotAverage.Value, app.plotRawData.Value, app.plotStats.Value);
                app.ppl = ppl_plot(app.UIAxes, app.ppl);
                delete(d)
            end
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            app.ppl.i_trial = min(app.ppl.i_trial + 1, app.ppl.nTrials);
            app.CurrentTrial.Value = app.ppl.i_trial;
%             app.ppl = ppl_plot(app.UIAxes, app.ppl, app.plotBaseline.Value, [app.baselineFrom.Value app.baselineTo.Value], app.plotAverage.Value, app.plotRawData.Value, app.plotStats.Value);
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            app.ppl.i_trial = max(1, app.ppl.i_trial - 1);
            app.CurrentTrial.Value = app.ppl.i_trial;
%             app.ppl = ppl_plot(app.UIAxes, app.ppl, app.plotBaseline.Value, [app.baselineFrom.Value app.baselineTo.Value], app.plotAverage.Value, app.plotRawData.Value, app.plotStats.Value);
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Button pushed function: ApplyEpochButton
        function ApplyEpochButtonPushed(app, event)
            % ADD MULTIPLE TRIGGERS OPTION
            app.ppl = ppl_epoch(app.ppl, app.triggerDropDown.Value, [app.preEditField.Value,app.postEditField.Value]);
            app.plotAverage.Value = 0;
            app.plotBaseline.Value = 0;
            app.plotRawData.Value = 0; 
            app.TrialCounter.Text = ['\' num2str( app.ppl.nTrials)];
%             app.ppl = ppl_plot(app.UIAxes, app.ppl, app.plotBaseline.Value, [app.baselineFrom.Value app.baselineTo.Value], app.plotAverage.Value, app.plotRawData.Value, app.plotStats.Value);
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Button pushed function: ApplyPreprocessing
        function ApplyPreprocessingButtonPushed(app, event)

            d = uiprogressdlg(app.UIFigure, 'Title', 'Processing', 'Indeterminate','off', 'Cancelable','on');

            for i = 1:app.ppl.nTrials
                if d.CancelRequested
                    break;
                else
                    d.Value = i/app.ppl.nTrials;
                    d.Message = ['Trial ' num2str(i) '/' num2str(app.ppl.nTrials)];
                end
                tmp_data = app.ppl.trial_raw(:,i); 
                % remove artifacts
                if app.RemoveArtifactsCheckBox.Value
                    tmp_data = ppl_removeBlinks(tmp_data, app.ArtifactTolerance.Value, app.ppl.missingDataThreshold/100, app.ppl.artifactSize, app.InterpolatemissingdataCheckBox.Value);
                end

                % low-pass filter

                if app.lowpassfilterCheckBox.Value
                    %                     d.Title('Filtering')
                    tmp_data = ppl_filter(tmp_data, app.ppl.srate, app.lowPassFrequency.Value, app.lowPassSteepness.Value);
                end

                % baseline correction

                if app.BaselineCheckBox.Value
                    app.plotBaseline.Enable = 1;
                    tmp_data = ppl_baseline(tmp_data, [app.baselineFrom.Value, app.baselineTo.Value], app.ppl.trial_time(:, 1));
                else
                    app.plotBaseline.Enable = 0;
                end

                app.ppl.trial_pupil(:,i) = tmp_data; 
                
            end

            % average over time
            if app.AverageSegmentCheckBox.Value && ~d.CancelRequested
                app.plotTimeAvg.Enable = 1;
                app.ppl.avg_pupil = ppl_timeAverage(app.ppl.trial_pupil, [app.segmentFrom.Value, app.segmentTo.Value], app.ppl.trial_time(:, 1));
            else
                app.plotTimeAvg.Enable = 0;
            end

            delete(d)
            app.plotRawData.Enable = 1;
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Button pushed function: LoadGroupFile
        function LoadGroupFileButtonPushed(app, event)
            [groupFile, groupPath] = uigetfile('*.*');
            app.ppl = ppl_loadTable(fullfile(groupPath, groupFile), app.ppl);
            app.nVariablesLabel.Text = [num2str(length(app.ppl.expTable.Properties.VariableNames)) ' variables identified !'];
            app.VariablesLabel.Items = app.ppl.expTable.Properties.VariableNames;
            app.groupFilenameLabel.Text = groupFile; 
        end

        % Button pushed function: ApplyGroup
        function ApplyGroupButtonPushed(app, event)

            if ~isempty(app.ppl.expTable) && ~isempty(app.GroupbyVariableTextArea.Value)
                [app.ppl.trialGroup, app.ppl.idGroup]= ppl_getGroup(app.ppl.expTable, app.GroupbyVariableTextArea.Value);
                app.nGroupsLabel.Text = [num2str(length(app.ppl.idGroup)) ' groups identified !'];
%                 app.ppl = ppl_plot(app.UIAxes, app.ppl, app.plotBaseline.Value, [app.baselineFrom.Value app.baselineTo.Value], app.plotAverage.Value, app.plotRawData.Value, app.plotStats.Value);
                app.ppl = ppl_plot(app.UIAxes, app.ppl);
                app.plotAverage.Enable = 1;
            end
        end

        % Value changed function: excludeTrials
        function excludeTrialsValueChanged(app, event)
            if app.excludeTrials.Value
                app.ppl.missingDataThreshold = app.excludeTrialsPrctg.Value;
            else
                app.ppl.missingDataThreshold = 100;
            end
            app.excludeTrialsPrctg.Enable = app.excludeTrials.Value;
        end

        % Value changed function: ArtifactSize
        function ArtifactSizeValueChanged(app, event)
            app.ppl.artifactSize = app.ppl.artifactSize.Value;
        end

        % Value changed function: excludeTrialsPrctg
        function excludeTrialsPrctgValueChanged(app, event)
            app.ppl.missingDataThreshold = app.excludeTrialsPrctg.Value;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % ADD PPL_EXPORT FUNCTION, maybe option to export to .csv
            % context menu can help
            export_fn = [app.ppl.file(1:2) '_pupilab']; % better to detect and remove the extension from app.ppl.file
            [export_fn,export_path,~] = uiputfile('*.mat','Export Data',export_fn);
            ppl = app.ppl;
            try
                if strcmp(export_fn(end-10:end), 'pupilab.mat')
                    save(fullfile(export_path, export_fn), 'ppl')
                else
                    app.warningText.Text = "Filename should end with 'pupilab.mat'";
                end
            catch
                app.warningText.Text = "Filename should end with 'pupilab.mat'";
            end
        end

        % Value changed function: BaselineCheckBox
        function BaselineCheckBoxValueChanged(app, event)
            value = app.BaselineCheckBox.Value;
            app.baselineTo.Enable = value;
            app.baselineFrom.Enable = value;
        end

        % Value changed function: lowpassfilterCheckBox
        function lowpassfilterCheckBoxValueChanged(app, event)
            value = app.lowpassfilterCheckBox.Value;
            app.lowPassSteepness.Enable = value;
            app.lowPassFrequency.Enable = value;
        end

        % Button pushed function: ApplyStats
        function ApplyStatsButtonPushed(app, event)
            if app.compareGroups.Value
                data1 = app.ppl.trial_pupil(:, app.ppl.trialGroup==1)';
                data2 = app.ppl.trial_pupil(:, app.ppl.trialGroup==2)';
                [app.ppl.H,app.ppl.P] = ttest2(data1, data2);
            end
            app.plotStats.Enable = 1;
        end

        % Value changed function: CurrentTrial
        function CurrentTrialValueChanged(app, event)
            app.ppl.i_trial = app.CurrentTrial.Value;
%             app.ppl = ppl_plot(app.UIAxes, app.ppl, app.plotBaseline.Value, [app.baselineFrom.Value app.baselineTo.Value], app.plotAverage.Value, app.plotRawData.Value, app.plotStats.Value);
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Value changed function: RemoveArtifactsCheckBox
        function RemoveArtifactsCheckBoxValueChanged(app, event)
            value = app.RemoveArtifactsCheckBox.Value;
            app.ArtifactSize.Enable = value;
            app.ArtifactTolerance.Enable = value;
        end

        % Value changed function: AverageSegmentCheckBox
        function AverageSegmentCheckBoxValueChanged(app, event)
            value = app.AverageSegmentCheckBox.Value;
            app.segmentTo.Enable = value;
            app.segmentFrom.Enable = value;
        end

        % Value changed function: plotBaseline
        function plotBaselineValueChanged(app, event)
            if app.plotBaseline.Value
                app.ppl.plotOpt.baselineRange = [app.baselineFrom.Value app.baselineTo.Value];
            else
                app.ppl.plotOpt.baselineRange = [];
            end
            app.ppl.plotOpt.baseline = app.plotBaseline.Value;
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Value changed function: plotAverage
        function plotAverageValueChanged(app, event)
            app.ppl.plotOpt.groupAvg = app.plotAverage.Value;
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Value changed function: plotRawData
        function plotRawDataValueChanged(app, event)
            app.ppl.plotOpt.raw = app.plotRawData.Value;
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Value changed function: plotStats
        function plotStatsValueChanged(app, event)
            app.ppl.plotOpt.stats = app.plotStats.Value;     
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Value changed function: plotTimeAvg
        function plotTimeAvgValueChanged(app, event)
            app.ppl.plotOpt.timeAvg = app.plotTimeAvg.Value;
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end

        % Value changed function: plotLegend
        function plotLegendValueChanged(app, event)
            app.ppl.plotOpt.legend = app.plotLegend.Value;
            app.ppl = ppl_plot(app.UIAxes, app.ppl);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Pupil Size')
            zlabel(app.UIAxes, 'Z')
            app.PreprocessingPanel = uipanel(app.UIFigure);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.BaselinePanel = uipanel(app.PreprocessingPanel);
            app.BaselinePanel.Title = 'Baseline';
            app.BaselineCheckBox = uicheckbox(app.BaselinePanel);
            app.BaselineCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineCheckBoxValueChanged, true);
            app.BaselineCheckBox.Text = 'Baseline';
            app.fromEditFieldLabel = uilabel(app.BaselinePanel);
            app.fromEditFieldLabel.Text = 'from';
            app.baselineFrom = uieditfield(app.BaselinePanel, 'numeric');
            app.baselineFrom.Enable = 'off';
            app.baselineFrom.Value = -1;
            app.toEditFieldLabel = uilabel(app.BaselinePanel);
            app.toEditFieldLabel.Text = 'to';
            app.baselineTo = uieditfield(app.BaselinePanel, 'numeric');
            app.baselineTo.Enable = 'off';
            app.ApplyPreprocessing = uibutton(app.PreprocessingPanel, 'push');
            app.ApplyPreprocessing.ButtonPushedFcn = createCallbackFcn(app, @ApplyPreprocessingButtonPushed, true);
            app.ApplyPreprocessing.Text = 'Apply';
            app.ArtifactsButtonGroup = uibuttongroup(app.PreprocessingPanel);
            app.ArtifactsButtonGroup.Title = 'Artifacts';
            app.TolerancesdLabel = uilabel(app.ArtifactsButtonGroup);
            app.TolerancesdLabel.Text = 'Tolerance (sd)';
            app.ArtifactTolerance = uieditfield(app.ArtifactsButtonGroup, 'numeric');
            app.ArtifactTolerance.Enable = 'off';
            app.ArtifactTolerance.Value = 10;
            app.SizemsLabel = uilabel(app.ArtifactsButtonGroup);
            app.SizemsLabel.Text = 'Size (ms)';
            app.ArtifactSize = uieditfield(app.ArtifactsButtonGroup, 'numeric');
            app.ArtifactSize.ValueChangedFcn = createCallbackFcn(app, @ArtifactSizeValueChanged, true);
            app.ArtifactSize.Enable = 'off';
            app.ArtifactSize.Value = 150;
            app.RemoveArtifactsCheckBox = uicheckbox(app.ArtifactsButtonGroup);
            app.RemoveArtifactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveArtifactsCheckBoxValueChanged, true);
            app.RemoveArtifactsCheckBox.Text = 'Remove Artifacts';
            app.excludeTrials = uicheckbox(app.ArtifactsButtonGroup);
            app.excludeTrials.ValueChangedFcn = createCallbackFcn(app, @excludeTrialsValueChanged, true);
            app.excludeTrials.Text = {'Exclude trials with more than'; '          % of missing data'};
            app.excludeTrialsPrctg = uieditfield(app.ArtifactsButtonGroup, 'numeric');
            app.excludeTrialsPrctg.Limits = [1 100];
            app.excludeTrialsPrctg.ValueChangedFcn = createCallbackFcn(app, @excludeTrialsPrctgValueChanged, true);
            app.excludeTrialsPrctg.Enable = 'off';
            app.excludeTrialsPrctg.Value = 20;
            app.InterpolatemissingdataCheckBox = uicheckbox(app.ArtifactsButtonGroup);
            app.InterpolatemissingdataCheckBox.Text = 'Interpolate missing data';
            app.FilterandResamplePanel = uipanel(app.PreprocessingPanel);
            app.FilterandResamplePanel.Title = 'Filter and Resample';
            app.lowpassfilterCheckBox = uicheckbox(app.FilterandResamplePanel);
            app.lowpassfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @lowpassfilterCheckBoxValueChanged, true);
            app.lowpassfilterCheckBox.Text = 'low-pass filter';
            app.lowPassFrequency = uieditfield(app.FilterandResamplePanel, 'numeric');
            app.lowPassFrequency.Limits = [1 50];
            app.lowPassFrequency.Enable = 'off';
            app.lowPassFrequency.Value = 3;
            app.SteepnessEditFieldLabel = uilabel(app.FilterandResamplePanel);
            app.SteepnessEditFieldLabel.Text = 'Steepness';
            app.lowPassSteepness = uieditfield(app.FilterandResamplePanel, 'numeric');
            app.lowPassSteepness.Limits = [0 1];
            app.lowPassSteepness.Enable = 'off';
            app.lowPassSteepness.Value = 0.95;
            app.FrequencyHzLabel = uilabel(app.FilterandResamplePanel);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.resampleatCheckBox = uicheckbox(app.FilterandResamplePanel);
            app.resampleatCheckBox.Enable = 'off';
            app.resampleatCheckBox.Text = 'resample at ';
            app.HzEditFieldLabel = uilabel(app.FilterandResamplePanel);
            app.HzEditFieldLabel.Enable = 'off';
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.FilterandResamplePanel, 'numeric');
            app.HzEditField.Enable = 'off';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.filenameLabel = uilabel(app.UIFigure);
            app.filenameLabel.Text = 'no file selected';
            app.EpochPanel = uipanel(app.UIFigure);
            app.EpochPanel.Title = 'Epoch';
            app.triggerDropDownLabel = uilabel(app.EpochPanel);
            app.triggerDropDownLabel.Text = 'trigger';
            app.triggerDropDown = uidropdown(app.EpochPanel);
            app.triggerDropDown.Items = {'no file selected'};
            app.triggerDropDown.Value = 'no file selected';
            app.preEditFieldLabel = uilabel(app.EpochPanel);
            app.preEditFieldLabel.Text = 'pre';
            app.preEditField = uieditfield(app.EpochPanel, 'numeric');
            app.preEditField.Value = -1;
            app.postEditFieldLabel = uilabel(app.EpochPanel);
            app.postEditFieldLabel.Text = 'post';
            app.postEditField = uieditfield(app.EpochPanel, 'numeric');
            app.postEditField.Value = 5;
            app.ApplyEpochButton = uibutton(app.EpochPanel, 'push');
            app.ApplyEpochButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyEpochButtonPushed, true);
            app.ApplyEpochButton.Text = 'Apply';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.Left = uibutton(app.PlotPanel, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Icon = 'left.png';
            app.Left.Text = '';
            app.Right = uibutton(app.PlotPanel, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Icon = 'right.png';
            app.Right.Text = '';
            app.TrialCounter = uilabel(app.PlotPanel);
            app.TrialCounter.Text = '/0';
            app.TrialEditFieldLabel = uilabel(app.PlotPanel);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.CurrentTrial = uieditfield(app.PlotPanel, 'numeric');
            app.CurrentTrial.Limits = [0 Inf];
            app.CurrentTrial.ValueChangedFcn = createCallbackFcn(app, @CurrentTrialValueChanged, true);
            app.plotBaseline = uicheckbox(app.PlotPanel);
            app.plotBaseline.ValueChangedFcn = createCallbackFcn(app, @plotBaselineValueChanged, true);
            app.plotBaseline.Enable = 'off';
            app.plotBaseline.Text = 'baseline';
            app.plotAverage = uicheckbox(app.PlotPanel);
            app.plotAverage.ValueChangedFcn = createCallbackFcn(app, @plotAverageValueChanged, true);
            app.plotAverage.Enable = 'off';
            app.plotAverage.Text = 'group average';
            app.plotRawData = uicheckbox(app.PlotPanel);
            app.plotRawData.ValueChangedFcn = createCallbackFcn(app, @plotRawDataValueChanged, true);
            app.plotRawData.Enable = 'off';
            app.plotRawData.Text = 'raw data';
            app.plotStats = uicheckbox(app.PlotPanel);
            app.plotStats.ValueChangedFcn = createCallbackFcn(app, @plotStatsValueChanged, true);
            app.plotStats.Enable = 'off';
            app.plotStats.Text = 'statistics';
            app.plotTimeAvg = uicheckbox(app.PlotPanel);
            app.plotTimeAvg.ValueChangedFcn = createCallbackFcn(app, @plotTimeAvgValueChanged, true);
            app.plotTimeAvg.Enable = 'off';
            app.plotTimeAvg.Text = 'time average';
            app.plotLegend = uicheckbox(app.PlotPanel);
            app.plotLegend.ValueChangedFcn = createCallbackFcn(app, @plotLegendValueChanged, true);
            app.plotLegend.Enable = 'off';
            app.plotLegend.Text = 'legend';
            app.plotPosition = uicheckbox(app.PlotPanel);
            app.plotPosition.Enable = 'off';
            app.plotPosition.Visible = 'off';
            app.plotPosition.Text = 'eye XY';
            app.GroupTrialsPanel = uipanel(app.UIFigure);
            app.GroupTrialsPanel.Title = 'Group Trials';
            app.LoadGroupFile = uibutton(app.GroupTrialsPanel, 'push');
            app.LoadGroupFile.ButtonPushedFcn = createCallbackFcn(app, @LoadGroupFileButtonPushed, true);
            app.LoadGroupFile.Text = 'Load Table';
            app.nVariablesLabel = uilabel(app.GroupTrialsPanel);
            app.nVariablesLabel.Text = '0 variables identifed';
            app.BalanceTrialsCheckBox = uicheckbox(app.GroupTrialsPanel);
            app.BalanceTrialsCheckBox.Enable = 'off';
            app.BalanceTrialsCheckBox.Text = 'Balance Trials';
            app.ApplyGroup = uibutton(app.GroupTrialsPanel, 'push');
            app.ApplyGroup.ButtonPushedFcn = createCallbackFcn(app, @ApplyGroupButtonPushed, true);
            app.ApplyGroup.Text = 'Apply';
            app.GroupbyVariableTextAreaLabel = uilabel(app.GroupTrialsPanel);
            app.GroupbyVariableTextAreaLabel.Text = 'Group by Variable';
            app.GroupbyVariableTextArea = uitextarea(app.GroupTrialsPanel);
            app.GroupbyVariableTextArea.Placeholder = 'Variable1 == 0; Variable1 == 1; Variable2 == 1 & Variable3 > 5;';
            app.VariablesLabel = uilistbox(app.GroupTrialsPanel);
            app.VariablesLabel.Items = {'no variables'};
            app.VariablesLabel.Value = 'no variables';
            app.nGroupsLabel = uilabel(app.GroupTrialsPanel);
            app.nGroupsLabel.Text = '0 groups identifed';
            app.groupFilenameLabel = uilabel(app.GroupTrialsPanel);
            app.groupFilenameLabel.Text = 'no file selected';
            app.AveragePanel = uipanel(app.UIFigure);
            app.AveragePanel.Title = 'Average';
            app.AverageSegmentCheckBox = uicheckbox(app.AveragePanel);
            app.AverageSegmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageSegmentCheckBoxValueChanged, true);
            app.AverageSegmentCheckBox.Text = 'Average time window';
            app.segmentFrom = uieditfield(app.AveragePanel, 'numeric');
            app.segmentFrom.Enable = 'off';
            app.fromEditFieldLabel_2 = uilabel(app.AveragePanel);
            app.fromEditFieldLabel_2.Text = 'from';
            app.toEditFieldLabel_2 = uilabel(app.AveragePanel);
            app.toEditFieldLabel_2.Text = 'to';
            app.segmentTo = uieditfield(app.AveragePanel, 'numeric');
            app.segmentTo.Enable = 'off';
            app.segmentTo.Value = 1;
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.Text = 'PupiLab';
            app.warningText = uilabel(app.UIFigure);
            app.warningText.Text = '';
            app.StatisticsPanel = uipanel(app.UIFigure);
            app.StatisticsPanel.Title = 'Statistics';
            app.ApplyStats = uibutton(app.StatisticsPanel, 'push');
            app.ApplyStats.ButtonPushedFcn = createCallbackFcn(app, @ApplyStatsButtonPushed, true);
            app.ApplyStats.Text = 'Apply';
            app.openStatPanel = uibutton(app.StatisticsPanel, 'push');
            app.openStatPanel.Enable = 'off';
            app.openStatPanel.Text = 'Statistic Panel';
            app.groupAnalyses = uibutton(app.StatisticsPanel, 'push');
            app.groupAnalyses.Enable = 'off';
            app.groupAnalyses.Text = 'Group Analyses';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PupilGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end